classdef testRobotPlotApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MakeDrink2Button  matlab.ui.control.Button
        MakeDrink1Button  matlab.ui.control.Button
        LoadUR10Button    matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         
       % UR10e = UR10e(0);
       % modelUR5 % Where I will put the loaded UR5
        qz = [0     0     0     0     0     0];
        
        qrUR5 = [pi/4	pi/2  -pi/2	0	0	0];
        qrUR5_ = [-pi/4 -pi/2 pi/2 0 0 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadUR10Button
        function LoadUR10ButtonPushed(app, event)
            
            axes(app.UIAxes);
            cla(app.UIAxes)
            
            % Make, save and plot some robots            
%             figure('visible','off'); % Makes the figure invisible when loading
            ax = gca;
                     
          %  app.UR10e = UR10e; %need UR10 model
            app.modelUR5.model.base = transl([-0.5,0,0]);
           
           app.modelUR5.model.animate(app.qrUR5); %-----------------------animate not recognized

            drawnow();
            
            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Transfer all the children across to the uiaxes parent EXCEPT
            % the green floors
            while 0 < length(ax.Children)                
                set(ax.Children(1),'Parent',app.UIAxes);
            end
            delete(get(gca,'parent'))
            
            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];
            
            % Turn off this load button and enable the move button
            app.MakeDrink1Button.Enable = true;
            app.MakeDrink2Button.Enable = true;
            app.LoadUR10Button.Enable = true;
        end

        % Button pushed function: MakeDrink1Button
        function MakeDrink1ButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR5,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                
                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelPuma.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                
                % Plot it by moving the handle of the robot
               animate(app.modelPuma,qmatrix(robotStepIndex,:),handle)
%                -------------------------------------------------------
                drawnow
            end
        end

        % Button pushed function: MakeDrink2Button
        function MakeDrink2ButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR5_,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                
                % Since findobj in animate dosen't work then we have to
                % find the handle ourselves
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.modelUR5.model.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                
                % Plot it by moving the handle of the robot
                animate(app.modelUR5.model,qmatrix(robotStepIndex,:),handle) 
                drawnow
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LoadUR10Button = uibutton(app.UIFigure, 'push');
            app.LoadUR10Button.ButtonPushedFcn = createCallbackFcn(app, @LoadUR10ButtonPushed, true);
            app.LoadUR10Button.Text = 'Load UR10';
            app.MakeDrink1Button = uibutton(app.UIFigure, 'push');
            app.MakeDrink1Button.ButtonPushedFcn = createCallbackFcn(app, @MakeDrink1ButtonPushed, true);
            app.MakeDrink1Button.Enable = 'off';
            app.MakeDrink1Button.Text = 'Make Drink #1';
            app.MakeDrink2Button = uibutton(app.UIFigure, 'push');
            app.MakeDrink2Button.ButtonPushedFcn = createCallbackFcn(app, @MakeDrink2ButtonPushed, true);
            app.MakeDrink2Button.Enable = 'off';
            app.MakeDrink2Button.Text = 'Make Drink #2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testRobotPlotApp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end