classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TakeControlofRogueObjectButton  matlab.ui.control.StateButton
        ObjectAvoidanceLabel            matlab.ui.control.Label
        GrabButton                      matlab.ui.control.Button
        TeachHandLabel                  matlab.ui.control.Label
        RadiusofObjectmmSlider          matlab.ui.control.Slider
        RadiusofObjectmmSliderLabel     matlab.ui.control.Label
        LightCurtainsLabel              matlab.ui.control.Label
        SelectedDrinkDropDown           matlab.ui.control.DropDown
        SelectedDrinkDropDownLabel      matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        JointDropDown                   matlab.ui.control.DropDown
        JointDropDownLabel              matlab.ui.control.Label
        ESTOPButton                     matlab.ui.control.StateButton
        ZButton_2                       matlab.ui.control.Button
        ZButton                         matlab.ui.control.Button
        YButton_2                       matlab.ui.control.Button
        YButton                         matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        CreateEnvironmentButton         matlab.ui.control.Button
        TeachButton                     matlab.ui.control.StateButton
        TakeControlofHandButton         matlab.ui.control.StateButton
        ControlUsingJoystickButton      matlab.ui.control.StateButton
        EndEffectorPositionLabel        matlab.ui.control.Label
        JointAngleControlLabel          matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        %default variables established 

        UR10E % Description
        Estop
        Gripper % Description
        Controlling_Arm = 0;

        Controlling_Hand = 0;
        Teach_ON;
        Code = "a56b34c";
        Objects
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UpdateViewButtonPushed(app, event)
 

        end

        % Button pushed function: CreateEnvironmentButton
        function CreateEnvironmentButtonPushed(app, event)


clear figure
close
%add matlab path of necessary files
addpath(genpath('Gripper'))
addpath(genpath('Arm'))
addpath(genpath("Objects"))
    addpath(genpath("Assignment Documents"))
    addpath(genpath("LightCurtain"))
    addpath(genpath("GUI&Environment"))
    
    Axis_Control = gca;
    Axis_Control.Clipping = "off";
    set(Axis_Control,'CameraViewAngleMode','Manual');
    hold on

try
     Test = Link('d',0.03705,'a',0.02151,'alpha',pi/2,'offset',0);
catch
    addpath(genpath('robot-9.10Small_Modified_20220202_WithVisionTB'))
    run('robot-9.10Small_Modified_20220202_WithVisionTB\robot-9.10Small_Modified\rvctools\startup_rvc.m')

end
base = transl(0,0,0); %base pose within environment 
app.UR10E = UR10e(); %ur10e created
app.UR10E.model.animate(app.UR10E.currentJoints); %start joint configuration
app.Gripper = Gripper(); %Gripper added

app.Gripper.move_gripper(app.UR10E.model.fkine(app.UR10E.currentJoints)) %gripper attached to end effector

environment = Environment(base); %Environment created

[PuttingSimulatedObjectsIntoTheEnvironment] = environment.build(base); %function builds objects

app.Objects = Create_Drinks(); %drinks created

set(gcf, "Position",[100,100,900,900]) %matlab camera position
pos = transl(0.5,-2,0.3) % Position of hand
L1 = LightCurtain(pos,[1.45,-1.2,0.3],[0,-1,0],[-1.45,1.45;0,0;0,0.3]); %Light curtain built
L1.AddPlane([1.45,0.4,0.3],[-1,0,0],[1.45,1.45;-1.15,0.4;0,0.3]) %plane added

app.UR10E.SetLightCurtains(L1);

        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
             if app.TeachButton.Value ==1 %if teach button pressed
            
                 %current position of ur10e
                 CurPos = app.UR10E.model.fkine(app.UR10E.currentJoints);

                 % x position of transform changed in negative direction
                 CurPos(1,4) = CurPos(1,4)-0.1;

            %assign joints configuration 
            Joints = app.UR10E.model.ikcon(CurPos,app.UR10E.currentJoints);

            %move to new joint configuration
            app.UR10E.move(Joints,app.Gripper);
             end

        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            if app.TeachButton.Value ==1
            
            
            CurPos = app.UR10E.model.fkine(app.UR10E.currentJoints);
     
            %positive x direction
            CurPos(1,4) = CurPos(1,4)+0.1;

            Joints = app.UR10E.model.ikcon(CurPos,app.UR10E.currentJoints);

           
            app.UR10E.move(Joints,app.Gripper);
            end
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
             if app.TeachButton.Value ==1
            CurPos = app.UR10E.model.fkine(app.UR10E.currentJoints);

            % negative y direction 
            CurPos(2,4) = CurPos(2,4)-0.1;

            Joints = app.UR10E.model.ikcon(CurPos,app.UR10E.currentJoints);

            app.UR10E.move(Joints,app.Gripper);

             end
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
             if app.TeachButton.Value ==1      
            CurPos = app.UR10E.model.fkine(app.UR10E.currentJoints);
       
            %positive y direction
            CurPos(2,4) = CurPos(2,4)+0.1;

            Joints = app.UR10E.model.ikcon(CurPos,app.UR10E.currentJoints);

            app.UR10E.move(Joints,app.Gripper);

             end
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
             if app.TeachButton.Value ==1  
            CurPos = app.UR10E.model.fkine(app.UR10E.currentJoints);

            % negative z direction
            CurPos(3,4) = CurPos(3,4)-0.1;

            Joints = app.UR10E.model.ikcon(CurPos,app.UR10E.currentJoints);

            app.UR10E.move(Joints,app.Gripper);

             end
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            
            if app.TeachButton.Value ==1
            CurPos = app.UR10E.model.fkine(app.UR10E.currentJoints);

            %positive z direction 
            CurPos(3,4) = CurPos(3,4)+0.1;

            Joints = app.UR10E.model.ikcon(CurPos,app.UR10E.currentJoints);

            app.UR10E.move(Joints,app.Gripper);

            end
        end

        % Value changed function: JointDropDown
        function JointDropDownValueChanged(app, event)
            
            value = app.JointDropDown.Value;
            x = app.UR10E.currentJoints
            y = app.UR10E.model.qlim
            value = str2num(value)
          numerator  = (x(value+1)-y(value+1,1))
          demoninator = (y(value+1,2)-y(value+1,1))
            value2 = numerator/demoninator
            app.Slider.Value = value2;

            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
            value = app.Slider.Value;
            y = app.UR10E.model.qlim;
            curJoints = app.UR10E.currentJoints;
           
            V = str2num(app.JointDropDown.Value)+1;
            %current joints changed to menu selection
            curJoints(V) = value*(y(V,2)-y(V,1))+y(V,1)

            app.UR10E.move(curJoints,app.Gripper);
            
        end

        % Value changed function: ControlUsingJoystickButton
        function ControlUsingJoystickButtonValueChanged(app, event)
            value = app.ControlUsingJoystickButton.Value;
            joy = vrjoystick(1);
            n=0
            dt = 0.15
             q= app.UR10E.currentJoints;
           %if button pressed
             if value ==1
                app.Controlling_Arm = 1;

                %while joystick is being controlled
                while(app.Controlling_Arm)

                    n=n+1;
                    [axes, buttons, povs] = read(joy)
                        dx = 0.1*axes(1);
                        dy = 0.1*axes(2);
                        dz = 0.1*buttons(1);
                        if dz==0
                            dz = -0.1*buttons(3);
                        end
                        dwx = 0.3*axes(4);
                        dwy = 0.3*axes(5);
                        dwz = 0.3*buttons(2);
                        if dwz == 0
                        dwz = -0.3*buttons(4);
                        end
                        x_1 = [dx;dy;dz;dwx;dwy;dwz;];
                        J = app.UR10E.model.jacob0(q);

                        IJ = inv(J);
                         dq = (inv(J)*x_1)*dt;
                        dq = transpose(dq);
                         q(1,:) = q+dq;
%                          app.UR10E.move(q,app.Gripper);
                        app.UR10E.model.animate(q);
                       T_Form =  app.UR10E.model.fkine(q);
                        app.Gripper.move_gripper(T_Form);
                        drawnow
                        pause(0.01)
                end
                app.UR10E.currentJoints = q;
            else
                app.Controlling_Arm = 0;
                return;
            end


        end

        % Value changed function: TakeControlofHandButton
        function TakeControlofHandButtonValueChanged(app, event)
            value = app.TakeControlofHandButton.Value;
             app.UR10E.HandTrigger = value;
%             if value ==1
%                
% %                 app.Controlling_Hand =1
% %                 app.L1.Connect_Joystick()
% %                 while app.Controlling_Hand ==1
% %                     app.L1.control_hand();
% %                     x = app.L1.CheckIntersection();
% %                     
% %                         if x==1
% %                            app.UR10E.EStop(1);
% %                         else
% %                             app.UR10E.EStop(0);
% %                         end
% % 
% %                 end
% % 
% % 
% 
%             else
% %                 app.Controlling_Hand =0
% %                 app.UR10E.EStop(0);
% 
% 
%             end


        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            value = app.ESTOPButton.Value;

                    app.UR10E.EStop(value)

%                     if value == 1
% 
% %                         uiwait
%                     else
% %                         uiresume
%                     end


        end

        % Value changed function: TeachButton
        function TeachButtonValueChanged(app, event)
            value = app.TeachButton.Value;
            app.Teach_ON = value;
        end

        % Value changed function: RadiusofObjectmmSlider
        function RadiusofObjectmmSliderValueChanged(app, event)
            value = app.RadiusofObjectmmSlider.Value;   
        end

        % Callback function
        function GrabButtonValueChanged(app, event)

        end

        % Button pushed function: GrabButton
        function GrabButtonPushed(app, event)
            %angle of gripper correlated with user slider input
            Angle = app.Gripper.encompassing_grip(app.RadiusofObjectmmSlider.Value/1000);
           %current angle joints
            StartAngles = app.Gripper.current_joints;
           %angles of three fingers 
            Angles = [Angle; Angle; Angle];
           %animate angles accordingly
            app.Gripper.animate(Angles);

            pause(2);


            app.Gripper.animate(StartAngles);
        end

        % Value changed function: SelectedDrinkDropDown
        function SelectedDrinkDropDownValueChanged(app, event)
            value = app.SelectedDrinkDropDown.Value;
                 % 0 - Not used
    % 1 =   Ginger Beer
    % 2 =   Rum
    % 3 =   Tonic Water
    % 4 =   Gin
    % 5 =   Dispense Ice
    % 6 =   Dispense Lime
    % 7 =   Dispense Sugar
    % 8 - Not used
    % 9 - Not used
    % 
    % a =   Pick glass up - Needed before adding dispenser ingredients otherwise
    %       things will teleport 
    % b =   Return glass if last ingredient was from dispenser - Also needed if
    %       need to add drinks to glass after adding dispenser ingredients
    % c =   Return arm to idle if last igredient was drink
 
            % drop down menu
            if value == "Gin & Tonic"
                app.Code = "a56b34c"; % code associated with drink 
            end
            if value == "Mojito"
                app.Code = "a657B23c";

            end
            if value == "Dark & Stormy"
                app.Code = "a56b21c";
            end
            if value == "Foghorn"
                app.Code = "a56b14a6bc"
            end
            if value == "Rum on the Rocks"
                app.Code = "a5b2c";
            end


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %will make drink according to drop down menu
            app.UR10E.makeDrink(app.Code,app.Objects,app.Gripper)
        end

        % Value changed function: TakeControlofRogueObjectButton
        function TakeControlofRogueObjectButtonValueChanged(app, event)
            value = app.TakeControlofRogueObjectButton.Value;
            app.UR10E.RogueTrigger = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.JointAngleControlLabel = uilabel(app.UIFigure);
            app.JointAngleControlLabel.Text = 'Joint Angle Control';
            app.EndEffectorPositionLabel = uilabel(app.UIFigure);
            app.EndEffectorPositionLabel.Text = 'End Effector Position';
            app.ControlUsingJoystickButton = uibutton(app.UIFigure, 'state');
            app.ControlUsingJoystickButton.ValueChangedFcn = createCallbackFcn(app, @ControlUsingJoystickButtonValueChanged, true);
            app.ControlUsingJoystickButton.Text = 'Control Using Joystick';
            app.TakeControlofHandButton = uibutton(app.UIFigure, 'state');
            app.TakeControlofHandButton.ValueChangedFcn = createCallbackFcn(app, @TakeControlofHandButtonValueChanged, true);
            app.TakeControlofHandButton.Text = 'Take Control of Hand';
            app.TeachButton = uibutton(app.UIFigure, 'state');
            app.TeachButton.ValueChangedFcn = createCallbackFcn(app, @TeachButtonValueChanged, true);
            app.TeachButton.Text = 'Teach';
            app.CreateEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.CreateEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @CreateEnvironmentButtonPushed, true);
            app.CreateEnvironmentButton.Text = 'Create Environment';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X-';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X+';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y-';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y+';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z-';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = 'Z+';
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.JointDropDownLabel = uilabel(app.UIFigure);
            app.JointDropDownLabel.Text = 'Joint';
            app.JointDropDown = uidropdown(app.UIFigure);
            app.JointDropDown.Items = {'0', '1', '2', '3', '4', '5'};
            app.JointDropDown.ValueChangedFcn = createCallbackFcn(app, @JointDropDownValueChanged, true);
            app.JointDropDown.Value = '0';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SelectedDrinkDropDownLabel = uilabel(app.UIFigure);
            app.SelectedDrinkDropDownLabel.Text = 'Selected Drink:';
            app.SelectedDrinkDropDown = uidropdown(app.UIFigure);
            app.SelectedDrinkDropDown.Items = {'Gin & Tonic', 'Mojito', 'Dark & Stormy', 'Foghorn', 'Rum on the Rocks', '', ''};
            app.SelectedDrinkDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedDrinkDropDownValueChanged, true);
            app.SelectedDrinkDropDown.Value = 'Gin & Tonic';
            app.LightCurtainsLabel = uilabel(app.UIFigure);
            app.LightCurtainsLabel.Text = 'Light Curtains';
            app.RadiusofObjectmmSliderLabel = uilabel(app.UIFigure);
            app.RadiusofObjectmmSliderLabel.Text = 'Radius of Object (mm)';
            app.RadiusofObjectmmSlider = uislider(app.UIFigure);
            app.RadiusofObjectmmSlider.Limits = [20 100];
            app.RadiusofObjectmmSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusofObjectmmSliderValueChanged, true);
            app.RadiusofObjectmmSlider.Value = 20;
            app.TeachHandLabel = uilabel(app.UIFigure);
            app.TeachHandLabel.Text = 'Teach Hand';
            app.GrabButton = uibutton(app.UIFigure, 'push');
            app.GrabButton.ButtonPushedFcn = createCallbackFcn(app, @GrabButtonPushed, true);
            app.GrabButton.Text = 'Grab';
            app.ObjectAvoidanceLabel = uilabel(app.UIFigure);
            app.ObjectAvoidanceLabel.Text = 'Object Avoidance ';
            app.TakeControlofRogueObjectButton = uibutton(app.UIFigure, 'state');
            app.TakeControlofRogueObjectButton.ValueChangedFcn = createCallbackFcn(app, @TakeControlofRogueObjectButtonValueChanged, true);
            app.TakeControlofRogueObjectButton.Text = 'Take Control of Rogue Object';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end